classdef HotStuff < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        HotStuffFigure                  matlab.ui.Figure
        deltaDisplaySelection           matlab.ui.container.ButtonGroup
        deltaRatioRB                    matlab.ui.control.RadioButton
        deltaGreenRB                    matlab.ui.control.RadioButton
        TabGroup                        matlab.ui.container.TabGroup
        IODataTab                       matlab.ui.container.Tab
        saveDRNaNBT                     matlab.ui.control.Button
        saveDRBT                        matlab.ui.control.Button
        saveDFNaNBT                     matlab.ui.control.Button
        saveRatioNaNBT                  matlab.ui.control.Button
        saveMaskNaNBT                   matlab.ui.control.Button
        saveMaskBT                      matlab.ui.control.Button
        saveDFBT                        matlab.ui.control.Button
        saveRatioBT                     matlab.ui.control.Button
        EndofburstframesEditField       matlab.ui.control.NumericEditField
        EndofburstframesEditFieldLabel  matlab.ui.control.Label
        StartofburstframesEditField     matlab.ui.control.NumericEditField
        StartofburstframesEditFieldLabel  matlab.ui.control.Label
        LoadRseqBT                      matlab.ui.control.Button
        LoadGseqBT                      matlab.ui.control.Button
        ComputeTab                      matlab.ui.container.Tab
        maxDR                           matlab.ui.control.NumericEditField
        minDR                           matlab.ui.control.NumericEditField
        MinmaxDeltaRLabel_2             matlab.ui.control.Label
        deltaRatioBT                    matlab.ui.control.Button
        maxRatio                        matlab.ui.control.NumericEditField
        minRatio                        matlab.ui.control.NumericEditField
        MinmaxDeltaRLabel               matlab.ui.control.Label
        maskImagesCB                    matlab.ui.control.CheckBox
        displayRawCB                    matlab.ui.control.CheckBox
        processBT                       matlab.ui.control.Button
        minR                            matlab.ui.control.NumericEditField
        MinmaxGRLabel_2                 matlab.ui.control.Label
        maxR                            matlab.ui.control.NumericEditField
        computeRGBT                     matlab.ui.control.Button
        minG                            matlab.ui.control.NumericEditField
        MinmaxGRLabel                   matlab.ui.control.Label
        maxG                            matlab.ui.control.NumericEditField
        maxDF                           matlab.ui.control.NumericEditField
        minDF                           matlab.ui.control.NumericEditField
        MinmaxDeltaFEditFieldLabel      matlab.ui.control.Label
        ProcessingPipelinePanel         matlab.ui.container.Panel
        thresholdEF                     matlab.ui.control.NumericEditField
        thresholdCB                     matlab.ui.control.CheckBox
        correctBleachCB                 matlab.ui.control.CheckBox
        greenOffsetEF                   matlab.ui.control.NumericEditField
        GreenoffsetLabel_3              matlab.ui.control.Label
        binningEF                       matlab.ui.control.NumericEditField
        BinningEditFieldLabel           matlab.ui.control.Label
        binCB                           matlab.ui.control.CheckBox
        redOffsetEF                     matlab.ui.control.NumericEditField
        GreenoffsetLabel_2              matlab.ui.control.Label
        subOffsetCB                     matlab.ui.control.CheckBox
        currentFrameEF                  matlab.ui.control.NumericEditField
        CurrentframeLabel               matlab.ui.control.Label
        backToOne                       matlab.ui.control.Button
        moveFastRight                   matlab.ui.control.Button
        moveFastLeft                    matlab.ui.control.Button
        moveRight                       matlab.ui.control.Button
        moveLeft                        matlab.ui.control.Button
        DeltaSeqBT                      matlab.ui.control.Button
        histo                           matlab.ui.control.UIAxes
        luminanceMask                   matlab.ui.control.UIAxes
        Rchannel                        matlab.ui.control.UIAxes
        ratio                           matlab.ui.control.UIAxes
        deltaG                          matlab.ui.control.UIAxes
        Gchannel                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        metadata        % Structure holding the description of the image files in
        dataIn          % Structure holding the input data
        dirIn           % Data directory
        fileName        % Name of the G sequence
        dataOut         % Structure holding all outputs
        dataPath        % Description
        meanGraw        % Mean of the rawImages stack
        meanGwi         % Mean of the workImages stack
        maskB           % Binary boolean mask
        maskD           % Binary double mask
        maskNaN         % zeros are onverted in NaNs
    end
    
    methods (Access = private)
        
        function displayGframe(app, frame)
            % The transposition is necessary to orient the images
            % consistently to what is shown in imageJ
            Glim = [app.minG.Value app.maxG.Value];
            app.Gchannel.XLimMode = 'auto';
            app.Gchannel.YLimMode = 'auto';            
            if app.displayRawCB.Value
                tmp = app.dataIn.rawImages(:,:,1,frame);
                imagesc (app.Gchannel,tmp') 
            else
                tmp = app.dataOut.workImages(:,:,1,frame);
                if app.maskImagesCB.Value
                    imagesc (app.Gchannel,tmp','AlphaData',app.maskD')
                    app.Gchannel.Color = [0 0 0];
                else
                    imagesc (app.Gchannel,tmp')
                end
            end
            app.Gchannel.CLim = Glim;
        end
        
        function displayDeltaG(app, frame)
            try
                if app.deltaGreenRB.Value
                    Dlim = [app.minDF.Value app.maxDF.Value];
                    tmp = app.dataOut.deltaG(:,:,frame);
                    if app.maskImagesCB.Value
                        imagesc (app.deltaG,tmp','AlphaData',app.maskD')
                        app.deltaG.Color = [0 0 0];
                    else
                        imagesc (app.deltaG,tmp')  
                    end
                    app.deltaG.CLim = Dlim;
                else
                    Dlim = [app.minDR.Value app.maxDR.Value];       %[app.minDF.Val ue app.maxDF.Value];
                    tmp = app.dataOut.deltaR(:,:,frame);
                    if app.maskImagesCB.Value
                        imagesc (app.deltaG,tmp','AlphaData',app.maskD')
                        app.deltaG.Color = [0 0 0];
                    else
                        imagesc (app.deltaG,tmp')  
                    end
                    app.deltaG.CLim = Dlim;
                end
            catch
                display ("No deltaF yet")
            end
        end
        
        function updateDisplay(app)
            frame = app.currentFrameEF.Value;
            displayGframe (app, frame)
            displayRframe (app, frame)
            displayDeltaG (app, frame)
            displayRatio (app, frame)
        end
        
        function binnedStack = binStack(app,bin)
            % This function compute the binned down image stack
            %
            % This function reduces the resolution of the image by the given factor by
            % creating larger pixels. The image size must be evenly divisible by the bin size
            
            tic
            disp ('Compute binning');
            binnedStack = [];
            % compute dimension of the binned images
            % how large is one image?

            newX = app.metadata.xdim/bin;
            newY = app.metadata.ydim/bin;
            % reserve space for the binned array
            binnedStack = zeros(newY, newX,2, app.metadata.frames, 'double');
            % Operate on one frame at the time
            % It processes both channels
            for chJ = 1:2
                for i=1:app.metadata.frames
                   % extract the current frame
                   cF = app.dataOut.workImages(:,:,chJ,i);
                   tmp = binImage(cF,bin);
                   binnedStack(:,:,chJ,i) = tmp';
                end
            end
            sizeB = size(binnedStack);
            app.dataOut.workImages = zeros(sizeB(1), sizeB(2),2, app.metadata.frames, 'double');
            % resize the workImages stack and store the binned data
            app.dataOut.workImages = zeros(sizeB(1), sizeB(2),2, app.metadata.frames, 'double');
            app.dataOut.workImages = binnedStack;
            %imagesc(app.imageExplore,mean(app.dataOut.workImages,4),'ButtonDownFcn',@mdImageClick);
        end
        
        function displayRframe(app, frame)
            Rlim = [app.minR.Value app.maxR.Value];
            if app.displayRawCB.Value
                tmp = app.dataIn.rawImages(:,:,2,frame);
                imagesc (app.Rchannel,tmp') 
            else
                tmp = app.dataOut.workImages(:,:,2,frame);
                if app.maskImagesCB.Value
                    imagesc (app.Rchannel,tmp','AlphaData',app.maskD')
                    app.Rchannel.Color = [0 0 0];
                else
                    imagesc (app.Rchannel,tmp')
                end
            end
            app.Rchannel.CLim = Rlim;            
        end
        
        function displayRatio(app, frame)
            try
                Rlim = [app.minRatio.Value app.maxRatio.Value];
                tmp = app.dataOut.ratio(:,:,frame);
                if app.maskImagesCB.Value
                    imagesc (app.ratio,tmp','AlphaData',app.maskD')
                    app.ratio.Color = [0 0 0];
                else
                    imagesc (app.ratio,tmp')
                end
                app.ratio.CLim = Rlim;            
            catch
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function openRGseq(app, event)

        end

        % Button pushed function: LoadGseqBT
        function openGseq(app, event)
            % November 11, 2025
            % Load the G sequence. This data is obligatory, obviously
            app.LoadGseqBT.BackgroundColor = [1 0 1];
            fmtSt = '*.tif';
            
            [selection,path_in,~] = uigetfile ([app.dirIn fmtSt]);
            % update the current data directory
            app.dirIn = path_in;
            app.fileName = selection;
            fileInfo = {'name' 'date' 'bytes' 'isdir' 'datenum'};    % structure for storage of file info
            if selection ~= 0
    
                fileInfo = dir([path_in selection]);
                timeNum = datestr(fileInfo.datenum);
                app.metadata.fileTime = timeNum (13:20);
                % Time of the day (hr:mn:ss) when the file has been generated

                pathAndFileIn = [path_in selection];
                app.metadata.tifInfo = imfinfo(pathAndFileIn);
                nframe = length(app.metadata.tifInfo);
                app.metadata.frames = nframe;
                app.metadata.xdim = app.metadata.tifInfo(1).Width;
                app.metadata.ydim = app.metadata.tifInfo(1).Height;
                app.metadata.tpoints = app.metadata.tifInfo(1).StripOffsets;
                app.dataIn.rawImages = zeros (app.metadata.xdim,app.metadata.ydim,1,nframe);
                tmp = zeros (app.metadata.xdim,app.metadata.ydim,1);
                for i=1:nframe
                    %offsettif=handles.tifInfo(i).Offset;
                    tmp = imread(pathAndFileIn,'Index',i);
                    %tmp=tmp-offsettif;
                    app.dataIn.rawImages(:,:,1,i) = tmp';     % the green channel has index 1
                end
                display('File Loaded');
                app.dataPath = path_in;
                % Green data loaded. The button turns green
                app.LoadGseqBT.BackgroundColor = [0 0.80 0];
                displayGframe (app,1);
            else
                app.LoadGseqBT.BackgroundColor = [0.96 0.96 0.96];
            end
            
        end

        % Button pushed function: DeltaSeqBT
        function computeDeltG(app, event)
            sizeD = size(app.dataOut.workImages);
            app.dataOut.deltaG = zeros(sizeD(1),sizeD(2),app.metadata.frames);
            bleachCorr = ones(app.metadata.frames,1);
            if app.correctBleachCB.Value
                % This function uses the red sequence to estimate the
                % photobleaching of the G sequence. This correction is applied
                % on each G frame previously of computing the diff G sequence
                for k=1:app.metadata.frames
                    tmp = app.dataOut.workImages(:,:,2,k);
                    bleachCorr(k) = mean(tmp.*app.maskNaN,"all","omitnan");
                end
                bleachCorr = bleachCorr(1)./bleachCorr;
            end            
            
            for i=2:app.metadata.frames
                app.dataOut.deltaG(:,:,i) = bleachCorr(i-1) * app.dataOut.workImages(:,:,1,i-1)...
                    - bleachCorr(i) * app.dataOut.workImages(:,:,1,i);
            end
            displayDeltaG (app,app.currentFrameEF.Value);
        end

        % Button pushed function: backToOne
        function setFrame1(app, event)
            app.currentFrameEF.Value = 1;
            updateDisplay(app)
        end

        % Button pushed function: moveRight
        function upOne(app, event)
            if app.currentFrameEF.Value < app.metadata.frames
                app.currentFrameEF.Value = app.currentFrameEF.Value + 1;
                updateDisplay(app)
            end
        end

        % Button pushed function: moveFastRight
        function upTen(app, event)
            if app.currentFrameEF.Value <= app.metadata.frames-10
                app.currentFrameEF.Value = app.currentFrameEF.Value + 10;
                updateDisplay(app)
            end

        end

        % Button pushed function: moveLeft
        function downOne(app, event)
            if app.currentFrameEF.Value > 1
                app.currentFrameEF.Value = app.currentFrameEF.Value - 1;
                updateDisplay(app)
            end
        end

        % Button pushed function: moveFastLeft
        function downTen(app, event)
            if app.currentFrameEF.Value > 11
                app.currentFrameEF.Value = app.currentFrameEF.Value - 10;
                updateDisplay(app)
            end            
        end

        % Callback function: currentFrameEF, deltaDisplaySelection, 
        % ...and 12 other components
        function repaintImages(app, event)
            updateDisplay(app)            
        end

        % Button pushed function: processBT
        function processStack(app, event)
            % the preprocessing is applied to the image stack.
            % This includes: offset subtraction, binning, masking, correction of photobleaching
            app.processBT.BackgroundColor = [1,0,0];
            drawnow
            % generate a work copy of the image stack
            app.dataOut.workImages = app.dataIn.rawImages;
            if app.subOffsetCB.Value
                % subtract offset from the two channels
                app.dataOut.workImages (:,:,1,:) = app.dataOut.workImages (:,:,1,:) - app.greenOffsetEF.Value;
                app.dataOut.workImages (:,:,2,:) = app.dataOut.workImages (:,:,2,:) - app.redOffsetEF.Value;
            end       

            if app.binCB.Value
                % Bin the fluorescence data before further processing
                app.dataOut.workImages = binStack (app,app.binningEF.Value);
            end

            if app.thresholdCB.Value
                % Compute threshold mask. Masking is only computed on the workImages stack

                thr = app.thresholdEF.Value;
                % use the mean of the green channel to compute the mask
                app.meanGwi = mean(app.dataOut.workImages(:,:,1,:),4);
                level = graythresh(app.meanGwi);
                % compute and plot the histogram
                cla (app.histo)
                gHisto = histogram(app.histo,app.meanGwi,220);

                % compute Gaussian fit
                app.histo.YScale = "lin";                
                binCenter = gHisto.BinEdges(1):gHisto.BinWidth:gHisto.BinEdges(gHisto.NumBins+1);
                binValues = gHisto.BinCounts;
                % find the index of the peak of the distribution
                [m iMax] = max(binValues);
                gF = fit(binCenter(1:iMax*2).',binValues(1:iMax*2).','gauss1');
                gaussPl =  gF.a1*exp(-((binCenter-gF.b1)/gF.c1).^2);
                hold(app.histo,'on')
                plot(app.histo,binCenter,gaussPl,"LineWidth",2)
                app.histo.YScale = "log";
                app.histo.YLim = [1 m];
                % Write out mean and SD on the axis title
                st = sprintf("Mean %i SD %i 5SD thr %i", floor(gF.b1), floor(gF.c1), floor(gF.b1+5*gF.c1));
                app.histo.Title.String = st;
                app.maskB = imbinarize(app.meanGwi, thr);               % Binary mask (logic, boolean)
                app.maskD = double (app.maskB);                         % Double value mask (for AlphaData channel)
                imagesc(app.luminanceMask,app.maskD')
                app.luminanceMask.Color = [0 0 0];
                app.maskNaN = app.maskD;
                app.maskNaN(~app.maskB) = NaN;                          % Zero value converted in NaN

                % now apply the maskNaN to the data
                app.dataOut.workImagesMasked = [];
                for frame=1:app.metadata.frames
                    app.dataOut.workImagesMasked(:,:,1,frame) = app.dataOut.workImages(:,:,1,frame) .* app.maskNaN;
                    app.dataOut.workImagesMasked(:,:,2,frame) = app.dataOut.workImages(:,:,1,frame) .* app.maskNaN;
                end
            end

            if app.correctBleachCB.Value

            end
            updateDisplay(app) 
            app.processBT.BackgroundColor = [0.96,0.96,0.96];
            drawnow

        end

        % Button pushed function: LoadRseqBT
        function loadRseq(app, event)
            % November 14, 2025
            % Load the R sequence. 
            % There are two possibilities:
            % the file contains 2 images. In that case these are the frames
            % at the beginning and at the end of the acquisition
            app.LoadRseqBT.BackgroundColor = [1 0 1];
            fmtSt = '*.tif';
            
            [selection,path_in,~] = uigetfile ([app.dirIn fmtSt]);
            % update the current data directory
            app.dirIn = path_in;
            app.fileName = selection;
            fileInfo = {'name' 'date' 'bytes' 'isdir' 'datenum'};    % structure for storage of file info
            if selection ~= 0    
                pathAndFileIn = [path_in selection];
                metadataR = imfinfo(pathAndFileIn);
                nframe = length(metadataR);
                tmp = zeros (app.metadata.xdim,app.metadata.ydim,1);
                for i=1:nframe
                    tmp = imread(pathAndFileIn,'Index',i);
                    app.dataIn.rawImages(:,:,2,i) = tmp';     % the red channel has index 2
                end
           
                % If there are only two frames we generate an artificial R
                % sequence by linearly composing the two frames and scaling
                % their relative weight with the frame number.
                if nframe == 2
                    %  generate a R sequence by composing the two files with
                    %  a linear scaling to account for photobleaching
                    firstR = app.dataIn.rawImages(:,:,2,1);
                    lastR = app.dataIn.rawImages(:,:,2,2);
                    for i=2:app.metadata.frames
                        w1 = (i-app.metadata.frames)/(1-app.metadata.frames);
                        w2 = (i-1)/(app.metadata.frames-1);
                        app.dataIn.rawImages(:,:,2,i) = w1 * firstR + w2 * lastR;
                    end
                end
                % Red data loaded. The button turns red
                app.LoadRseqBT.BackgroundColor = [0.80 0 0];
                displayRframe (app,1);
            else
                app.LoadRseqBT.BackgroundColor = [0.96 0.96 0.96];
            end
            
        end

        % Button pushed function: computeRGBT
        function ratioRG(app, event)
            % compute the ration R / G
            app.computeRGBT.BackgroundColor = [1 0 0];
            drawnow
            app.dataOut.ratio = [];
            for i=1:app.metadata.frames
                app.dataOut.ratio(:,:,i) = app.dataOut.workImages(:,:,2,i) ./ app.dataOut.workImages(:,:,1,i);
            end
            displayRatio (app,app.currentFrameEF.Value);
            app.computeRGBT.BackgroundColor = [0.96 0.96 0.96];

        end

        % Button pushed function: saveRatioBT, saveRatioNaNBT
        function saveRatio(app, event)
            % Save the ratio image stack to a TIFF multi image file
            % The filename is predefined

            % Define the filename for the multi-TIFF file
            app.saveRatioBT.BackgroundColor = [1 0.5 0];
            if event.Source.Text == "Save Ratio"
                fileOut = [app.dirIn 'ratioRG.tiff'];
                tmp = single(app.dataOut.ratio);
            else
                fileOut = [app.dirIn 'ratioRG masked.tiff'];
                tmp = single(app.dataOut.ratio .* app.maskNaN);
            end
            % Create a Tiff object
            t = Tiff(fileOut, 'w');

            % Set the TIFF tags
            sizeTif = size(app.dataOut.ratio);
            tagstruct.ImageWidth = sizeTif(2);
            tagstruct.ImageLength = sizeTif(1);
            tagstruct.SamplesPerPixel = 1; % Grayscale
            tagstruct.Photometric = Tiff.Photometric.MinIsBlack;
            tagstruct.SampleFormat = Tiff.SampleFormat.IEEEFP;      % floating
            tagstruct.BitsPerSample = 32;
            tagstruct.SamplesPerPixel = 1;
            tagstruct.Compression = Tiff.Compression.None;
%            tagstruct.PLANARCONFIG = Tiff.PlanarConfig.Chunky;
            tagstruct.Software = 'MatLab';
         %   tagstruct.ROWS_PER_STRIP = height; % For strip-based storage

            % Write each frame to the TIFF file
            for k = 1:app.metadata.frames
                % Set the current directory to write to
                t.setTag(tagstruct);
    
                % Write the data for the current frame
                t.write(tmp(:,:,k));
    
                % Write the next directory for the next frame
                if k < app.metadata.frames
                    t.writeDirectory();
                end
            end

            % Close the Tiff object
            t.close();
            app.saveRatioBT.BackgroundColor = [0.96 0.96 0.96];
        end

        % Button pushed function: saveDFBT, saveDFNaNBT
        function saveDF(app, event)
            % Save the Delta F image stack to a TIFF multi image file
            % The filename is predefined

            app.saveDFBT.BackgroundColor = [1 0.5 0];
            % Define the filename for the multi-TIFF file
            if event.Source.Text == "Save DF"
                fileOut = [app.dirIn 'deltaF.tiff'];
                tmp = single(app.dataOut.deltaG);
            else
                fileOut = [app.dirIn 'deltaF masked.tiff'];
                tmp = single(app.dataOut.deltaG .* app.maskNaN);
            end

            % Create a Tiff object
            t = Tiff(fileOut, 'w');
        
            % Set the TIFF tags
            sizeTif = size(app.dataOut.ratio);
            tagstruct.ImageWidth = sizeTif(2);
            tagstruct.ImageLength = sizeTif(1);
            tagstruct.SamplesPerPixel = 1; % Grayscale
            tagstruct.Photometric = Tiff.Photometric.MinIsBlack;
            tagstruct.SampleFormat = Tiff.SampleFormat.IEEEFP;      % floating
            tagstruct.BitsPerSample = 32;
            tagstruct.SamplesPerPixel = 1;
            tagstruct.Compression = Tiff.Compression.None;
%            tagstruct.PlanarConfig = Tiff.PlanarConfig.Chunky;
            tagstruct.Software = 'MatLab';
         %   tagstruct.ROWS_PER_STRIP = height; % For strip-based storage

            % Write each frame to the TIFF file
            for k = 1:app.metadata.frames
                % Set the current directory to write to
                t.setTag(tagstruct);
    
                % Write the data for the current frame
                t.write(tmp(:,:,k));
    
                % Write the next directory for the next frame
                if k < app.metadata.frames
                    t.writeDirectory();
                end
            end

            % Close the Tiff object
            t.close();
            app.saveDFBT.BackgroundColor = [0.96 0.96 0.96];
        end

        % Button pushed function: saveMaskBT, saveMaskNaNBT
        function saveMask(app, event)
            % Save the binary mask stack to a TIFF file
            % The filename is predefined
            % Define the filename for the multi-TIFF file
            if event.Source.Text == 'Save Mask';
                fileOut = [app.dirIn 'mask.tiff'];
                tmp = single(app.maskD);
            else
                fileOut = [app.dirIn 'mask NaN.tiff'];
                tmp = single(app.maskNaN);
            end

            % Create a Tiff object
            t = Tiff(fileOut, 'w');
        
            % Set the TIFF tags
            sizeTif = size(app.dataOut.ratio);
            tagstruct.ImageWidth = sizeTif(2);
            tagstruct.ImageLength = sizeTif(1);
            tagstruct.SamplesPerPixel = 1;                          % Grayscale
            tagstruct.Photometric = Tiff.Photometric.MinIsBlack;
            tagstruct.SampleFormat = 1;                             % integer 8 bit (uint8)
%            tagstruct.BitsPerSample = 8;
            tagstruct.SampleFormat = Tiff.SampleFormat.IEEEFP;      % floating 32 bits.
            tagstruct.BitsPerSample = 32;
            tagstruct.SamplesPerPixel = 1;
            tagstruct.Compression = Tiff.Compression.None;
%            tagstruct.PlanarConfig = Tiff.PlanarConfig.Chunky;
            tagstruct.Software = 'MatLab';
         %   tagstruct.ROWS_PER_STRIP = height; % For strip-based storage

            % Set the current directory to write to
            t.setTag(tagstruct);
    
            % Write the data for the current frame
%            t.write(uint8(app.maskB));
            t.write(tmp);
    %                t.writeDirectory();
            % Close the Tiff object
            t.close();
        end

        % Button pushed function: deltaRatioBT
        function computeDeltaRatio(app, event)
            app.deltaRatioBT.BackgroundColor = [1 0 0];
            sizeD = size(app.dataOut.workImages);
            app.dataOut.deltaR = zeros(sizeD(1),sizeD(2),app.metadata.frames);
            
            for i=2:app.metadata.frames
                app.dataOut.deltaR(:,:,i) = app.dataOut.ratio(:,:,i) - app.dataOut.ratio(:,:,i-1);
            end
            displayDeltaG (app,app.currentFrameEF.Value);
            app.deltaRatioBT.BackgroundColor = [0.96 0.96 0.96];

        end

        % Button pushed function: saveDRBT, saveDRNaNBT
        function SaveDR(app, event)
            % Save the Delta R image stack to a TIFF multi image file
            % The filename is predefined

            app.saveDRBT.BackgroundColor = [1 0.5 0];
            % Define the filename for the multi-TIFF file
            if event.Source.Text == "Save DR"
                fileOut = [app.dirIn 'deltaRatio.tiff'];
                tmp = single(app.dataOut.deltaR);
            else
                fileOut = [app.dirIn 'deltaRatio masked.tiff'];
                tmp = single(app.dataOut.deltaR .* app.maskNaN);
            end

            % Create a Tiff object
            t = Tiff(fileOut, 'w');
        
            % Set the TIFF tags
            sizeTif = size(app.dataOut.ratio);
            tagstruct.ImageWidth = sizeTif(2);
            tagstruct.ImageLength = sizeTif(1);
            tagstruct.SamplesPerPixel = 1; % Grayscale
            tagstruct.Photometric = Tiff.Photometric.MinIsBlack;
            tagstruct.SampleFormat = Tiff.SampleFormat.IEEEFP;      % floating
            tagstruct.BitsPerSample = 32;
            tagstruct.SamplesPerPixel = 1;
            tagstruct.Compression = Tiff.Compression.None;
%            tagstruct.PlanarConfig = Tiff.PlanarConfig.Chunky;
            tagstruct.Software = 'MatLab';
         %   tagstruct.ROWS_PER_STRIP = height; % For strip-based storage

            % Write each frame to the TIFF file
            for k = 1:app.metadata.frames
                % Set the current directory to write to
                t.setTag(tagstruct);
    
                % Write the data for the current frame
                t.write(tmp(:,:,k));
    
                % Write the next directory for the next frame
                if k < app.metadata.frames
                    t.writeDirectory();
                end
            end

            % Close the Tiff object
            t.close();
            app.saveDRBT.BackgroundColor = [0.96 0.96 0.96];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.HotStuffFigure = uifigure('Visible', 'off');
            app.HotStuffFigure.Name = 'MATLAB App';
            app.Gchannel = uiaxes(app.HotStuffFigure);
            title(app.Gchannel, 'Green')
            zlabel(app.Gchannel, 'Z')
            app.Gchannel.XTick = [];
            app.Gchannel.YTick = [];
            app.deltaG = uiaxes(app.HotStuffFigure);
            title(app.deltaG, '-')
            zlabel(app.deltaG, 'Z')
            app.deltaG.XTick = [];
            app.deltaG.YTick = [];
            app.ratio = uiaxes(app.HotStuffFigure);
            title(app.ratio, 'Ratio R/G')
            zlabel(app.ratio, 'Z')
            app.ratio.XTick = [];
            app.ratio.YTick = [];
            app.Rchannel = uiaxes(app.HotStuffFigure);
            title(app.Rchannel, 'Red')
            zlabel(app.Rchannel, 'Z')
            app.Rchannel.XTick = [];
            app.Rchannel.YTick = [];
            app.luminanceMask = uiaxes(app.HotStuffFigure);
            title(app.luminanceMask, 'Mask')
            zlabel(app.luminanceMask, 'Z')
            app.luminanceMask.XTick = [];
            app.luminanceMask.YTick = [];
            app.histo = uiaxes(app.HotStuffFigure);
            title(app.histo, 'Histrogram Mean G')
            zlabel(app.histo, 'Z')
            app.histo.YTick = [];
            app.TabGroup = uitabgroup(app.HotStuffFigure);
            app.IODataTab = uitab(app.TabGroup);
            app.IODataTab.Title = 'I/O Data';
            app.LoadGseqBT = uibutton(app.IODataTab, 'push');
            app.LoadGseqBT.ButtonPushedFcn = createCallbackFcn(app, @openGseq, true);
            app.LoadGseqBT.Tooltip = {'Load a sequence of G images contained in a multi-tiff file'};
            app.LoadGseqBT.Text = 'Load G seq';
            app.LoadRseqBT = uibutton(app.IODataTab, 'push');
            app.LoadRseqBT.ButtonPushedFcn = createCallbackFcn(app, @loadRseq, true);
            app.LoadRseqBT.Tooltip = {'Load a sequence of  R images contained in a multi-tiff file. Two possibilities: 1) the same number of images than the G channel. In that case the ratio is computed by directly ratioing the G with the average of the R frames that precede and follow the G frame. If only two R frames are present, as in the high speed acquisition, an artificial R frame is computed by linear interpolation.'};
            app.LoadRseqBT.Text = 'Load R seq';
            app.StartofburstframesEditFieldLabel = uilabel(app.IODataTab);
            app.StartofburstframesEditFieldLabel.Text = 'Start of burst (frames):';
            app.StartofburstframesEditField = uieditfield(app.IODataTab, 'numeric');
            app.StartofburstframesEditField.Limits = [1 Inf];
            app.StartofburstframesEditField.ValueDisplayFormat = '%.0f';
            app.StartofburstframesEditField.Tooltip = {'Frame of the sequence that corresponds to the beginning of the extracellular stimulation'};
            app.StartofburstframesEditField.Value = 1;
            app.EndofburstframesEditFieldLabel = uilabel(app.IODataTab);
            app.EndofburstframesEditFieldLabel.Text = 'End of burst (frames):';
            app.EndofburstframesEditField = uieditfield(app.IODataTab, 'numeric');
            app.EndofburstframesEditField.Limits = [1 Inf];
            app.EndofburstframesEditField.ValueDisplayFormat = '%.0f';
            app.EndofburstframesEditField.Tooltip = {'Frame of the sequence that corresponds to the beginning of the extracellular stimulation'};
            app.EndofburstframesEditField.Value = 1;
            app.saveRatioBT = uibutton(app.IODataTab, 'push');
            app.saveRatioBT.ButtonPushedFcn = createCallbackFcn(app, @saveRatio, true);
            app.saveRatioBT.Tooltip = {'Save multi tiff ratio R/G'};
            app.saveRatioBT.Text = 'Save Ratio';
            app.saveDFBT = uibutton(app.IODataTab, 'push');
            app.saveDFBT.ButtonPushedFcn = createCallbackFcn(app, @saveDF, true);
            app.saveDFBT.Tooltip = {'Save the derivative of the G channel as a multi tiff file.'};
            app.saveDFBT.Text = 'Save DF';
            app.saveMaskBT = uibutton(app.IODataTab, 'push');
            app.saveMaskBT.ButtonPushedFcn = createCallbackFcn(app, @saveMask, true);
            app.saveMaskBT.Tooltip = {'Save binary luminance mask'};
            app.saveMaskBT.Text = 'Save Mask';
            app.saveMaskNaNBT = uibutton(app.IODataTab, 'push');
            app.saveMaskNaNBT.ButtonPushedFcn = createCallbackFcn(app, @saveMask, true);
            app.saveMaskNaNBT.Tooltip = {'Save luminance mask. Pixels under the threshold are set to NaN.'};
            app.saveMaskNaNBT.Text = 'Save M NaN';
            app.saveRatioNaNBT = uibutton(app.IODataTab, 'push');
            app.saveRatioNaNBT.ButtonPushedFcn = createCallbackFcn(app, @saveRatio, true);
            app.saveRatioNaNBT.Tooltip = {'Save multi tiff ratio R/G. Masked data are set to NaN. It can be opened in ImageJ.'};
            app.saveRatioNaNBT.Text = 'Save R NaN';
            app.saveDFNaNBT = uibutton(app.IODataTab, 'push');
            app.saveDFNaNBT.ButtonPushedFcn = createCallbackFcn(app, @saveDF, true);
            app.saveDFNaNBT.Tooltip = {'Save the derivative of the G channel as a multi tiff file. The images are masked to NaN. '};
            app.saveDFNaNBT.Text = 'Save DF NaN';
            app.saveDRBT = uibutton(app.IODataTab, 'push');
            app.saveDRBT.ButtonPushedFcn = createCallbackFcn(app, @SaveDR, true);
            app.saveDRBT.Tooltip = {'Save the derivative of the R/G ratio as a multi tiff file.'};
            app.saveDRBT.Text = 'Save DR';
            app.saveDRNaNBT = uibutton(app.IODataTab, 'push');
            app.saveDRNaNBT.ButtonPushedFcn = createCallbackFcn(app, @SaveDR, true);
            app.saveDRNaNBT.Tooltip = {'Save the derivative of the R/G ratio channel as a multi tiff file. The images are masked to NaN. '};
            app.saveDRNaNBT.Text = 'Save DR NaN';
            app.ComputeTab = uitab(app.TabGroup);
            app.ComputeTab.Title = 'Compute';
            app.DeltaSeqBT = uibutton(app.ComputeTab, 'push');
            app.DeltaSeqBT.ButtonPushedFcn = createCallbackFcn(app, @computeDeltG, true);
            app.DeltaSeqBT.Tooltip = {'Compute the derivative of the G channel. In other words, the frame j of this sequence is the difference of frame j minus frame (j-1). So this sequence starts with index 2.'};
            app.DeltaSeqBT.Text = 'Delta Seq';
            app.moveLeft = uibutton(app.ComputeTab, 'push');
            app.moveLeft.ButtonPushedFcn = createCallbackFcn(app, @downOne, true);
            app.moveLeft.Text = '<';
            app.moveRight = uibutton(app.ComputeTab, 'push');
            app.moveRight.ButtonPushedFcn = createCallbackFcn(app, @upOne, true);
            app.moveRight.Text = '>';
            app.moveFastLeft = uibutton(app.ComputeTab, 'push');
            app.moveFastLeft.ButtonPushedFcn = createCallbackFcn(app, @downTen, true);
            app.moveFastLeft.Text = '<<';
            app.moveFastRight = uibutton(app.ComputeTab, 'push');
            app.moveFastRight.ButtonPushedFcn = createCallbackFcn(app, @upTen, true);
            app.moveFastRight.Text = '>>';
            app.backToOne = uibutton(app.ComputeTab, 'push');
            app.backToOne.ButtonPushedFcn = createCallbackFcn(app, @setFrame1, true);
            app.backToOne.Text = 'Start';
            app.CurrentframeLabel = uilabel(app.ComputeTab);
            app.CurrentframeLabel.Text = 'Current frame:';
            app.currentFrameEF = uieditfield(app.ComputeTab, 'numeric');
            app.currentFrameEF.Limits = [1 Inf];
            app.currentFrameEF.ValueDisplayFormat = '%.0f';
            app.currentFrameEF.ValueChangedFcn = createCallbackFcn(app, @repaintImages, true);
            app.currentFrameEF.Value = 1;
            app.ProcessingPipelinePanel = uipanel(app.ComputeTab);
            app.ProcessingPipelinePanel.Title = 'Processing Pipeline';
            app.subOffsetCB = uicheckbox(app.ProcessingPipelinePanel);
            app.subOffsetCB.Tooltip = {'Subtract off set from both channels'};
            app.subOffsetCB.Text = 'Subtract dark offset';
            app.GreenoffsetLabel_2 = uilabel(app.ProcessingPipelinePanel);
            app.GreenoffsetLabel_2.Text = 'R:';
            app.redOffsetEF = uieditfield(app.ProcessingPipelinePanel, 'numeric');
            app.redOffsetEF.Limits = [0 Inf];
            app.redOffsetEF.ValueDisplayFormat = '%.0f';
            app.redOffsetEF.Tooltip = {'Value of the dark background to be measured with ImageJ in a R frame'};
            app.redOffsetEF.Value = 220;
            app.binCB = uicheckbox(app.ProcessingPipelinePanel);
            app.binCB.Tooltip = {'Select to perform binning before of the other processing steps'};
            app.binCB.Text = 'Bin images';
            app.BinningEditFieldLabel = uilabel(app.ProcessingPipelinePanel);
            app.BinningEditFieldLabel.Text = 'Binning:';
            app.binningEF = uieditfield(app.ProcessingPipelinePanel, 'numeric');
            app.binningEF.Limits = [1 8];
            app.binningEF.ValueDisplayFormat = '%.0f';
            app.binningEF.Tooltip = {'Value of the binning'};
            app.binningEF.Value = 2;
            app.GreenoffsetLabel_3 = uilabel(app.ProcessingPipelinePanel);
            app.GreenoffsetLabel_3.Text = 'Offset G:';
            app.greenOffsetEF = uieditfield(app.ProcessingPipelinePanel, 'numeric');
            app.greenOffsetEF.Limits = [0 Inf];
            app.greenOffsetEF.ValueDisplayFormat = '%.0f';
            app.greenOffsetEF.Tooltip = {'Value of the dark background to be measured with ImageJ in a G frame'};
            app.greenOffsetEF.Value = 228;
            app.correctBleachCB = uicheckbox(app.ProcessingPipelinePanel);
            app.correctBleachCB.Tooltip = {'During the computations (Delta sequence and generation of ratioed images) the frames are corrected for photobleaching with the acquired or estimated red channel. '};
            app.correctBleachCB.Text = 'Correct for photobleaching';
            app.thresholdCB = uicheckbox(app.ProcessingPipelinePanel);
            app.thresholdCB.Tooltip = {'Compute a mask to segment out the high S/N parts of the image'};
            app.thresholdCB.Text = 'Threshold images';
            app.thresholdEF = uieditfield(app.ProcessingPipelinePanel, 'numeric');
            app.thresholdEF.Limits = [0 Inf];
            app.thresholdEF.ValueDisplayFormat = '%.0f';
            app.thresholdEF.Tooltip = {'Value of the threshold. After the Process button is pressed, the title of the histogram plot contains a suggested value for the threshold computed as the 5 sigma of the distribution of the background pixels'};
            app.MinmaxDeltaFEditFieldLabel = uilabel(app.ComputeTab);
            app.MinmaxDeltaFEditFieldLabel.Text = 'Min max  Delta F:';
            app.minDF = uieditfield(app.ComputeTab, 'numeric');
            app.minDF.Limits = [-100 Inf];
            app.minDF.ValueDisplayFormat = '%.0f';
            app.minDF.ValueChangedFcn = createCallbackFcn(app, @repaintImages, true);
            app.maxDF = uieditfield(app.ComputeTab, 'numeric');
            app.maxDF.Limits = [0 Inf];
            app.maxDF.ValueDisplayFormat = '%.0f';
            app.maxDF.ValueChangedFcn = createCallbackFcn(app, @repaintImages, true);
            app.maxDF.Value = 20;
            app.maxG = uieditfield(app.ComputeTab, 'numeric');
            app.maxG.Limits = [0 Inf];
            app.maxG.ValueDisplayFormat = '%.0f';
            app.maxG.ValueChangedFcn = createCallbackFcn(app, @repaintImages, true);
            app.maxG.Value = 500;
            app.MinmaxGRLabel = uilabel(app.ComputeTab);
            app.MinmaxGRLabel.Text = 'Min max  Green:';
            app.minG = uieditfield(app.ComputeTab, 'numeric');
            app.minG.Limits = [0 Inf];
            app.minG.ValueDisplayFormat = '%.0f';
            app.minG.ValueChangedFcn = createCallbackFcn(app, @repaintImages, true);
            app.computeRGBT = uibutton(app.ComputeTab, 'push');
            app.computeRGBT.ButtonPushedFcn = createCallbackFcn(app, @ratioRG, true);
            app.computeRGBT.Tooltip = {'Ratio between the G and R sequence.'};
            app.computeRGBT.Text = 'Ratio';
            app.maxR = uieditfield(app.ComputeTab, 'numeric');
            app.maxR.Limits = [0 Inf];
            app.maxR.ValueDisplayFormat = '%.0f';
            app.maxR.ValueChangedFcn = createCallbackFcn(app, @repaintImages, true);
            app.maxR.Value = 500;
            app.MinmaxGRLabel_2 = uilabel(app.ComputeTab);
            app.MinmaxGRLabel_2.Text = 'Min max  Red:';
            app.minR = uieditfield(app.ComputeTab, 'numeric');
            app.minR.Limits = [0 Inf];
            app.minR.ValueDisplayFormat = '%.0f';
            app.minR.ValueChangedFcn = createCallbackFcn(app, @repaintImages, true);
            app.processBT = uibutton(app.ComputeTab, 'push');
            app.processBT.ButtonPushedFcn = createCallbackFcn(app, @processStack, true);
            app.processBT.Tooltip = {'Process all steps checked in the Procesin Pipeline panel'};
            app.processBT.Text = 'Process';
            app.displayRawCB = uicheckbox(app.ComputeTab);
            app.displayRawCB.ValueChangedFcn = createCallbackFcn(app, @repaintImages, true);
            app.displayRawCB.Tooltip = {'If this is selected the Green and Red windows will display the original raw input file. If deselected, the windows will display the processed images'};
            app.displayRawCB.Text = 'Display raw input file';
            app.displayRawCB.Value = true;
            app.maskImagesCB = uicheckbox(app.ComputeTab);
            app.maskImagesCB.ValueChangedFcn = createCallbackFcn(app, @repaintImages, true);
            app.maskImagesCB.Tooltip = {'If selected the data are masked in the low S/N areas '};
            app.maskImagesCB.Text = 'Show masked images';
            app.maskImagesCB.Value = true;
            app.MinmaxDeltaRLabel = uilabel(app.ComputeTab);
            app.MinmaxDeltaRLabel.Text = 'Min max  R/G:';
            app.minRatio = uieditfield(app.ComputeTab, 'numeric');
            app.minRatio.Limits = [-100 Inf];
            app.minRatio.ValueDisplayFormat = '%.2f';
            app.minRatio.ValueChangedFcn = createCallbackFcn(app, @repaintImages, true);
            app.maxRatio = uieditfield(app.ComputeTab, 'numeric');
            app.maxRatio.Limits = [0 Inf];
            app.maxRatio.ValueDisplayFormat = '%.2f';
            app.maxRatio.ValueChangedFcn = createCallbackFcn(app, @repaintImages, true);
            app.maxRatio.Value = 1.4;
            app.deltaRatioBT = uibutton(app.ComputeTab, 'push');
            app.deltaRatioBT.ButtonPushedFcn = createCallbackFcn(app, @computeDeltaRatio, true);
            app.deltaRatioBT.Tooltip = {'Derivative of the ratio between the G and R sequence.'};
            app.deltaRatioBT.Text = 'Delta Ratio';
            app.MinmaxDeltaRLabel_2 = uilabel(app.ComputeTab);
            app.MinmaxDeltaRLabel_2.Text = 'Min max  Delta R:';
            app.minDR = uieditfield(app.ComputeTab, 'numeric');
            app.minDR.Limits = [-100 Inf];
            app.minDR.ValueDisplayFormat = '%.2f';
            app.minDR.ValueChangedFcn = createCallbackFcn(app, @repaintImages, true);
            app.minDR.Tooltip = {'Min value for the display of the ratio derivative'};
            app.minDR.Value = -0.1;
            app.maxDR = uieditfield(app.ComputeTab, 'numeric');
            app.maxDR.Limits = [0 Inf];
            app.maxDR.ValueDisplayFormat = '%.2f';
            app.maxDR.ValueChangedFcn = createCallbackFcn(app, @repaintImages, true);
            app.maxDR.Tooltip = {'Max value for the display of the ratio derivative'};
            app.maxDR.Value = 0.2;
            app.deltaDisplaySelection = uibuttongroup(app.HotStuffFigure);
            app.deltaDisplaySelection.SelectionChangedFcn = createCallbackFcn(app, @repaintImages, true);
            app.deltaGreenRB = uiradiobutton(app.deltaDisplaySelection);
            app.deltaGreenRB.Text = 'Delta Green';
            app.deltaGreenRB.Value = true;
            app.deltaRatioRB = uiradiobutton(app.deltaDisplaySelection);
            app.deltaRatioRB.Text = 'Delta ratio R/G';
            % Show the figure after all components are created
            app.HotStuffFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HotStuff

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.HotStuffFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.HotStuffFigure)
        end
    end
end